classdef BTVN2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        UIAxes                 matlab.ui.control.UIAxes
        UIAxes2                matlab.ui.control.UIAxes
        OpenButton             matlab.ui.control.Button
        SaveButton             matlab.ui.control.Button
        Slider                 matlab.ui.control.Slider
        MethodDropDownLabel    matlab.ui.control.Label
        MethodDropDown         matlab.ui.control.DropDown
        AuthorLNguynKhoaLabel  matlab.ui.control.Label
        StudentID1911405Label  matlab.ui.control.Label
        EditField              matlab.ui.control.NumericEditField
        DecreaseButton         matlab.ui.control.Button
        IncreaseButton         matlab.ui.control.Button
        AutoscaleButton        matlab.ui.control.Button
    end


    properties (Access = public)
        Property
        I
        ketqua
        temp
        valueu
    end


    methods (Access = private)

        % Button pushed function: OpenButton
        function OpenButtonPushed(app, event)
[file,path] = uigetfile('*.*');
    if file == 0
	% User clicked the Cancel button.
            figure(app.UIFigure);
            return;
    end
app.I = imread(strcat(path,file));
    switch ndims(app.I)
        case 2
            figure(app.UIFigure);
            imshow(app.I, 'parent', app.UIAxes);
        case 3
            app.I=rgb2gray(app.I);
            figure(app.UIFigure);
            imshow(app.I, 'parent', app.UIAxes);
    end
app.Slider.Value = app.EditField.Value;
app.valueu=app.Slider.Value;
set(app.AutoscaleButton,'Enable','On')
set(app.MethodDropDown,'Enable','On')
set(app.Slider,'Enable','On');
set(app.EditField,'Enable','On');
set(app.DecreaseButton,'Enable','On');
set(app.IncreaseButton,'Enable','On');
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
                        startingFolder = pwd;
defaultFileName = fullfile(startingFolder, 'Result.jpg');
[baseFileName, folder] = uiputfile(defaultFileName, 'Specify a file');
if baseFileName == 0
	% User clicked the Cancel button.
	return;
end
fullFileName = fullfile(folder, baseFileName);
imwrite(app.ketqua, fullFileName);

        end

        % Value changed function: MethodDropDown
        function MethodDropDownValueChanged(app, event)
            value = app.MethodDropDown.Value;
        switch value
            case 'select'
                app.ketqua=app.I;
                       return
            case 'global'
                app.ketqua=imbinarize(app.I,value);

            otherwise 
                app.ketqua=imbinarize(app.I,value); 
        end
        imshow(app.ketqua, 'parent', app.UIAxes2);
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
         app.valueu = app.Slider.Value;
       app.EditField.Value=app.Slider.Value;
       app.ketqua=imbinarize(app.I,app.valueu);
       imshow(app.ketqua, 'parent', app.UIAxes2);
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
         app.valueu=app.Slider.Value;
         app.ketqua=imbinarize(app.I,app.valueu);
         imshow(app.ketqua, 'parent', app.UIAxes2);
         
        end

        % Button pushed function: IncreaseButton
        function IncreaseButtonPushed(app, event)
            app.valueu=app.valueu+0.005;
            app.temp=imbinarize(app.I,app.valueu);
            imshow(app.temp, 'parent', app.UIAxes2);
            if app.valueu <= 1
                app.Slider.Value=app.valueu;
                app.EditField.Value=app.valueu;
            elseif app.valueu >1
               uialert(app.UIFigure,'Error! Value has reached the higher bound!','Execution error');
            end         
        end

        % Button pushed function: DecreaseButton
        function DecreaseButtonPushed(app, event)
            app.valueu=app.valueu-0.005;
            app.temp=imbinarize(app.I,app.valueu);
            imshow(app.temp, 'parent', app.UIAxes2);
            if app.valueu >= 0
                app.Slider.Value=app.valueu;
                app.EditField.Value=app.valueu;
            elseif app.valueu < 0
               uialert(app.UIFigure,'Error! Value has reached the lower bound!','Execution error');
            end          
        end

        % Callback function
        function AutoscaleButtonPushed(app, event)
%%%
        end

        % Button pushed function: AutoscaleButton
        function AutoscaleButtonPushed2(app, event)
         a=graythresh(app.I);
         new=imbinarize(app.I,a);
         imshow(new, 'parent', app.UIAxes2);
         app.EditField.Value=a;
         app.Slider.Value=a;
         app.valueu=a;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Before Procession')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'After Procession')
            app.OpenButton = uibutton(app.UIFigure, 'push');
            app.OpenButton.ButtonPushedFcn = createCallbackFcn(app, @OpenButtonPushed, true);
            app.OpenButton.Text = 'Open';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.Slider = uislider(app.UIFigure);
            app.Slider.Limits = [0 1];
            app.Slider.MajorTickLabels = {'0', '', '', '', '', '', '', '', '', '', '1'};
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.Enable = 'off';
            app.Slider.Value = 0.5;
            app.MethodDropDownLabel = uilabel(app.UIFigure);
            app.MethodDropDownLabel.Enable = 'off';
            app.MethodDropDownLabel.Text = 'Method';
            app.MethodDropDown = uidropdown(app.UIFigure);
            app.MethodDropDown.Items = {'Select', 'Adaptive', 'Global'};
            app.MethodDropDown.ValueChangedFcn = createCallbackFcn(app, @MethodDropDownValueChanged, true);
            app.MethodDropDown.Enable = 'off';
            app.MethodDropDown.Value = 'Select';
            app.AuthorLNguynKhoaLabel = uilabel(app.UIFigure);
            app.AuthorLNguynKhoaLabel.Text = 'Author: Lê Nguyên Khoa';
            app.StudentID1911405Label = uilabel(app.UIFigure);
            app.StudentID1911405Label.Text = 'Student ID: 1911405';
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.EditField.Enable = 'off';
            app.EditField.Value = 0.5;
            app.DecreaseButton = uibutton(app.UIFigure, 'push');
            app.DecreaseButton.ButtonPushedFcn = createCallbackFcn(app, @DecreaseButtonPushed, true);
            app.DecreaseButton.Enable = 'off';
            app.DecreaseButton.Text = 'Decrease';
            app.IncreaseButton = uibutton(app.UIFigure, 'push');
            app.IncreaseButton.ButtonPushedFcn = createCallbackFcn(app, @IncreaseButtonPushed, true);
            app.IncreaseButton.Enable = 'off';
            app.IncreaseButton.Text = 'Increase';
            app.AutoscaleButton = uibutton(app.UIFigure, 'push');
            app.AutoscaleButton.ButtonPushedFcn = createCallbackFcn(app, @AutoscaleButtonPushed2, true);
            app.AutoscaleButton.Enable = 'off';
            app.AutoscaleButton.Text = 'Autoscale';
        end
    end

    methods (Access = public)

        % Construct app
        function app = BTVN2

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end