classdef ImageApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        ImageAxes             matlab.ui.control.UIAxes
        HistogramAxes         matlab.ui.control.UIAxes
        ToolPanel             matlab.ui.container.Panel
        LoadButton            matlab.ui.control.Button
        SliderLabel           matlab.ui.control.Label
        Slider                matlab.ui.control.Slider
        Thresh_holdImageAxes  matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        
        function updateimage(app,imagefile)
                global I;
                try
                    im = imread(imagefile);
                    I=im;
                    if size(I, 3) == 3
                        I = rgb2gray(I);
                    end
                catch ME
                    % If problem reading image, display error message
                    uialert(app.UIFigure, ME.message, 'Image Error');
                    return;
                end                        
            % Create histograms 
                try
                    % Display the grayscale image
                    imagesc(app.ImageAxes,im);
                    % Plot all histograms with the same data for grayscale
                    histr = histogram(app.HistogramAxes,im);                  
                catch
                    % Error when image is not grayscale or truecolor
                    uialert(app.UIFigure, 'Image must be grayscale or truecolor.', 'Image Error');
                end
                
                % Set y axes limits based on largest bin count
                app.HistogramAxes.YLim = [0 255];
                app.HistogramAxes.YTick = round([0 255/2 255], 2, 'significant');
            end
        end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Configure image axes
            app.ImageAxes.Colormap = gray(256);
            axis(app.ImageAxes, 'image');
            app.Thresh_holdImageAxes.Colormap = gray(256);
            axis(app.Thresh_holdImageAxes, 'image');
        end

        % Callback function
        function DropDownValueChanged(app, event)

            % Update the image and histograms
            updateimage(app, app.DropDown.Value);
            
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
               
            % Display uigetfile dialog
            filterspec = {'*.jpg;*.tif;*.png;*.gif','All Image Files'};
            [f, p] = uigetfile(filterspec);
           
            % Make sure user didn't cancel uigetfile dialog
            if (ischar(p))
               fname = [p f];
               updateimage(app, fname);
            end
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            global I;
            value = app.Slider.Value;
            BW=Thresh_holding(I,value);
            imagesc(app.Thresh_holdImageAxes,BW)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Image App';
            app.ImageAxes = uiaxes(app.UIFigure);
            title(app.ImageAxes, {'Original Image'; ''})
            app.ImageAxes.XTick = [];
            app.ImageAxes.XTickLabel = {'[ ]'};
            app.ImageAxes.YTick = [];
            app.HistogramAxes = uiaxes(app.UIFigure);
            title(app.HistogramAxes, 'Histogram')
            xlabel(app.HistogramAxes, 'Intensity')
            ylabel(app.HistogramAxes, 'Pixels')
            app.HistogramAxes.XLim = [0 255];
            app.HistogramAxes.XTick = [0 128 255];
            app.ToolPanel = uipanel(app.UIFigure);
            app.ToolPanel.Title = 'Tool';
            app.LoadButton = uibutton(app.ToolPanel, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load Image';
            app.SliderLabel = uilabel(app.ToolPanel);
            app.SliderLabel.Text = 'Slider';
            app.Slider = uislider(app.ToolPanel);
            app.Slider.Limits = [0 1];
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Thresh_holdImageAxes = uiaxes(app.UIFigure);
            title(app.Thresh_holdImageAxes, 'Thresh Hold Image')
            app.Thresh_holdImageAxes.XTick = [];
            app.Thresh_holdImageAxes.XTickLabel = {'[ ]'};
            app.Thresh_holdImageAxes.YTick = [];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ImageApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end