classdef khang_vi < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure      matlab.ui.Figure           % UI Figure
        UIAxes        matlab.ui.control.UIAxes   % Title
        UIAxes2       matlab.ui.control.UIAxes   % Title
        UIAxes3       matlab.ui.control.UIAxes   % Title
        LabelSlider   matlab.ui.control.Label    % Slider
        Slider        matlab.ui.control.Slider   % [0 100]
        LabelDropDown matlab.ui.control.Label    % Drop Down
        DropDown      matlab.ui.control.DropDown % Thresholding, One Level,...
        Button        matlab.ui.control.Button   % Load Image
        Button2       matlab.ui.control.Button   % Save Image
    end


    properties (Access = public)
        I % Description
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

        end

        % Button button pushed function
        function loadImage(app)
            [file,path] = uigetfile('*.*');
            if file == 0
        	% User clicked the Cancel button.
                    figure(app.UIFigure);
                    return;
            end
            app.I = imread(strcat(path,file));
            switch ndims(app.I)
                case 2
                    figure(app.UIFigure);
                    imshow(app.I, [], 'parent', app.UIAxes);
                case 3
                    app.I=rgb2gray(app.I);
                    figure(app.UIFigure);
                    imshow(app.I, 'parent', app.UIAxes);
            end
            app.Slider.Value = app.EditField.Value;
            app.valueu=app.Slider.Value;
            set(app.AutoscaleButton,'Enable','On')
            set(app.MethodDropDown,'Enable','On')
            set(app.Slider,'Enable','On');
            set(app.EditField,'Enable','On');
            set(app.DecreaseButton,'Enable','On');
            set(app.IncreaseButton,'Enable','On');
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            setAutoResize(app, app.UIFigure, true)
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title');
            xlabel(app.UIAxes, 'X');
            ylabel(app.UIAxes, 'Y');
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Title');
            xlabel(app.UIAxes2, 'X');
            ylabel(app.UIAxes2, 'Y');
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Title');
            xlabel(app.UIAxes3, 'X');
            ylabel(app.UIAxes3, 'Y');
            app.LabelSlider = uilabel(app.UIFigure);
            app.LabelSlider.Text = 'Slider';
            app.Slider = uislider(app.UIFigure);
            app.LabelDropDown = uilabel(app.UIFigure);
            app.LabelDropDown.Text = 'Drop Down';
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.Items = {'Thresholding', 'One Level', 'Two Level', 'Auto Level'};
            app.DropDown.Value = 'Thresholding';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @loadImage);
            app.Button.Text = 'Load Image';
            app.Button2 = uibutton(app.UIFigure, 'push');
            app.Button2.Text = 'Save Image';
        end
    end

    methods (Access = public)

        % Construct app
        function app = khang_vi()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end